classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesiredPressureSliderLabel      matlab.ui.control.Label
        DesiredPressureSlider           matlab.ui.control.Slider
        STARTButton                     matlab.ui.control.Button
        RunTimesEditFieldLabel          matlab.ui.control.Label
        RunTimesEditField               matlab.ui.control.NumericEditField
        ObjectListBoxLabel              matlab.ui.control.Label
        ObjectListBox                   matlab.ui.control.ListBox
        GripperAngledegreeSpinnerLabel  matlab.ui.control.Label
        GripperAngledegreeSpinner       matlab.ui.control.Spinner
        GripperPositionmSpinnerLabel    matlab.ui.control.Label
        GripperPositionmSpinner         matlab.ui.control.Spinner
        FingerSpacingmSpinnerLabel      matlab.ui.control.Label
        FingerSpacingmSpinner           matlab.ui.control.Spinner
        ContactLampLabel                matlab.ui.control.Label
        ContactLamp                     matlab.ui.control.Lamp
        FrictionCoefficientSpinnerLabel  matlab.ui.control.Label
        FrictionCoefficientSpinner      matlab.ui.control.Spinner
        ObjectMasskgSpinnerLabel        matlab.ui.control.Label
        ObjectMasskgSpinner             matlab.ui.control.Spinner
        LiftLampLabel                   matlab.ui.control.Label
        LiftLamp                        matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AutomaticSwitchLabel            matlab.ui.control.Label
        AutomaticSwitch                 matlab.ui.control.Switch
        ObjectSizemSpinnerLabel         matlab.ui.control.Label
        ObjectSizemSpinner              matlab.ui.control.Spinner
        STOPButton                      matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            clc;
   
            global object miu Pd radius
            
            
            runTime = app.RunTimesEditField.Value;
            object = app.ObjectListBox.Value;
            miu = app.FrictionCoefficientSpinner.Value;
            radius = app.ObjectSizemSpinner.Value;
            mass = app.ObjectMasskgSpinner.Value;
            Pd = app.DesiredPressureSlider.Value;
            
            if strcmp(app.AutomaticSwitch.Value,'Off')
                fingerSpacing = app.FingerSpacingmSpinner.Value;
                verticalMove = app.GripperPositionmSpinner.Value;
                angle = app.GripperAngledegreeSpinner.Value;
            elseif strcmp(app.AutomaticSwitch.Value,'On')
                if strcmp(object,'Ball')
                    angle = 70;
                    verticalMove = 0.003/0.005 * (radius - 0.02) - 0.067;
                    fingerSpacing = 0.007/0.005 * (radius - 0.02) + 0.014;
                elseif strcmp(object,'Cubic')
                    fingerSpacing = 0.028;
                    verticalMove = -0.06;
                    angle = 70;
                end
            end
                        

            global q0 q
            global m EI EA dt u nv ne refLen voronoiRefLen
            global gravity ctime Len Wid g
            global ScaleSolver Ident2nv
            global tol maximum_iter invMat E
            global rho mMat
            global mapCons ForceAll
            
            %% Constraint
            global uProjected imposedAcceleration
            
            %% Initialization
            Len = 0.088;   %input('Please input the length of the rod: \n');
            rho = 1000;   %input('Please input the density of your material: \n');
            Wid = [0.062 0.090];  %input('Please input the radius of your rod: \n');
            
            E = 1e4;
            EI = E*1/12*Wid(1)*Wid(2)^3;
            EA = E*Wid(1)*Wid(2);
            dt = 0.005;
            nv = 20;
            ne = nv - 1;
            tol = 1e-6;
            g = [0, -9.81];
            maximum_iter = 100;
            
            
            % Indicate whether images should be saved
            saveImage = 0;


            %% Geometry of the rod
            nodes = zeros(nv, 1);
            for c=1:nv
                nodes(c, 1) = (c-1)*Len*cos(pi*(180-angle)/180)/ ne - fingerSpacing / 2;
                nodes(c, 2) = -(c-1)*Len*sin(pi*(180-angle)/180)/ne +  verticalMove;
            end
            dm = Wid(1) * Wid(2) * Len * rho / ne;
            
            %% Multiplier for Force & Jacobian (Reference to original DER code)
            ScaleSolver = EI / Len^2;
            
            %% Compute Mass and Gravity
            m = zeros(2*nv, 1);
            gravity = zeros(2*nv, 1);
            for c=1:nv
                if c==1
                    m( 2 * (c-1) + 1 : 2 * (c-1) + 2) = dm/2;
                elseif c==nv
                    m( 2 * (c-1) + 1 : 2 * (c-1) + 2) = dm/2;
                else
                    m( 2 * (c-1) + 1 : 2 * (c-1) + 2) = dm;
                end
            end
            
            
            %get inverse mass matrix
            invMat = diag(1./m);
            mMat = diag(m);
            Ident2nv = eye(2*nv);
            imposedAcceleration = zeros(2*nv, 1);
            gravity(2:2:2*nv) = g(2);
            %% Reference Length and Voronoi Length (Reference to original DER code)
            refLen = zeros(ne, 1);
            for c=1:ne
                dx = nodes(c+1, :) - nodes(c, :);
                refLen(c) = norm(dx);
            end
            voronoiRefLen = zeros(nv, 1);
            for c=1:nv
                if c==1
                    voronoiRefLen(c) = 0.5 * refLen(c);
                elseif c==nv
                    voronoiRefLen(c) = 0.5 * refLen(c-1);
                else
                    voronoiRefLen(c) = 0.5 * (refLen(c-1) + refLen(c));
                end
            end
            
            %% Initial
            q0 = zeros(2*nv, 1);
            for c=1:nv
                q0( 2 * (c-1) + 1) = nodes(c,1);
                q0( 2 * (c-1) + 2) = nodes(c,2);
            end
            q = q0;
            
            %% Constrained dofs
            mapCons = zeros(2*nv,1);
            mapCons(1:4) = 1;
            
            
            ForceAll = zeros(2*nv,1);
            
            u = (q - q0) / dt;
            uProjected = u;


            %% save image
            imageDirectory = date;
            if (saveImage~=0)
                mkdir(imageDirectory);
            end


            %% Time marching
            Nsteps = round(runTime/dt);
            tau = zeros(Nsteps,1);
            ctime = 0;
            endY = zeros(Nsteps, 1);
            ki0 = zeros(ne,1);
            tempP = zeros(Nsteps,1);
            Fy = zeros(Nsteps,1);
  
            
            for t = 1:Nsteps
                fprintf('time is: %f \n',t);
                
                tempP(t) = Pd;
               
                                 
                [P,Ps] = getPressure(t,Nsteps,tempP(1:t));


                tau(t,1) = 0.0088*P;
                
                % Get natural curvature
                for c = 1:ne
                    ki0(c) = -(refLen(c)/EI)*tau(t,1);
                end
                
                timeIntegration(ki0);
                
                u = ( q - q0) / dt;
                ctime = ctime + dt;
                uProjected = u;
                
                % Update x0
                q0 = q;


                % Plot everything
                rod_id(q, ctime, saveImage, imageDirectory);
                endY(t) = q(end);
                plot(app.UIAxes,(0:t)*dt,Ps,'b');
                xlabel(app.UIAxes,'time (s)');
                ylabel(app.UIAxes,'pressure (kPa)');
                title(app.UIAxes,'Instantaneous Pressure');
                grid(app.UIAxes,'on');


                % get lift force
                col = 'ro';
                if length(find(mapCons(5:2*nv))) >= 1 
                    app.ContactLamp.Color = [0 1 0];
                    Fy(t) = getLift;
                    if Fy(t) + mass*g(2)/2 > 0
                        app.LiftLamp.Color = [0 1 0];
                        col = 'go';
                    else
                        app.LiftLamp.Color = [0.5 0.5 0.5];
                    end
                else
                    app.ContactLamp.Color = [0.5 0.5 0.5];
                    app.LiftLamp.Color = [0.5 0.5 0.5];
                end  
                plot(app.UIAxes2,Ps(t),2*Fy(t),col,'MarkerSize',3);
                hold(app.UIAxes2,'on');
                ylabel(app.UIAxes2,'Lifting Force (N)');
                xlabel(app.UIAxes2,'Instant Pressure (kPa)');
                grid(app.UIAxes2,'on');


            end
           
                
        end

        % Value changing function: DesiredPressureSlider
        function DesiredPressureSliderValueChanging(app, event)
             global Pd
             Pd = event.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)

             closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DesiredPressureSliderLabel = uilabel(app.UIFigure);
            app.DesiredPressureSliderLabel.Text = 'Desired Pressure';
            app.DesiredPressureSlider = uislider(app.UIFigure);
            app.DesiredPressureSlider.Limits = [0 80];
            app.DesiredPressureSlider.ValueChangingFcn = createCallbackFcn(app, @DesiredPressureSliderValueChanging, true);
            app.DesiredPressureSlider.Value = 20;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RunTimesEditFieldLabel = uilabel(app.UIFigure);
            app.RunTimesEditFieldLabel.Text = 'Run Time (s)';
            app.RunTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTimesEditField.Limits = [0 10];
            app.RunTimesEditField.Value = 3;
            app.ObjectListBoxLabel = uilabel(app.UIFigure);
            app.ObjectListBoxLabel.Text = 'Object';
            app.ObjectListBox = uilistbox(app.UIFigure);
            app.ObjectListBox.Items = {'Ball', 'Cubic'};
            app.ObjectListBox.Value = 'Ball';
            app.GripperAngledegreeSpinnerLabel = uilabel(app.UIFigure);
            app.GripperAngledegreeSpinnerLabel.Text = 'Gripper Angle (degree)';
            app.GripperAngledegreeSpinner = uispinner(app.UIFigure);
            app.GripperAngledegreeSpinner.Limits = [45 70];
            app.GripperAngledegreeSpinner.Value = 50;
            app.GripperPositionmSpinnerLabel = uilabel(app.UIFigure);
            app.GripperPositionmSpinnerLabel.Text = 'Gripper Position (m)';
            app.GripperPositionmSpinner = uispinner(app.UIFigure);
            app.GripperPositionmSpinner.Step = 0.01;
            app.GripperPositionmSpinner.Limits = [-0.1 0];
            app.GripperPositionmSpinner.Value = -0.06;
            app.FingerSpacingmSpinnerLabel = uilabel(app.UIFigure);
            app.FingerSpacingmSpinnerLabel.Text = 'Finger Spacing (m)';
            app.FingerSpacingmSpinner = uispinner(app.UIFigure);
            app.FingerSpacingmSpinner.Step = 0.001;
            app.FingerSpacingmSpinner.Limits = [0.015 0.065];
            app.FingerSpacingmSpinner.Value = 0.035;
            app.ContactLampLabel = uilabel(app.UIFigure);
            app.ContactLampLabel.Text = 'Contact';
            app.ContactLamp = uilamp(app.UIFigure);
            app.FrictionCoefficientSpinnerLabel = uilabel(app.UIFigure);
            app.FrictionCoefficientSpinnerLabel.Text = 'Friction Coefficient';
            app.FrictionCoefficientSpinner = uispinner(app.UIFigure);
            app.FrictionCoefficientSpinner.Step = 0.01;
            app.FrictionCoefficientSpinner.Limits = [0 0.5];
            app.FrictionCoefficientSpinner.Value = 0.25;
            app.ObjectMasskgSpinnerLabel = uilabel(app.UIFigure);
            app.ObjectMasskgSpinnerLabel.Text = 'Object Mass (kg)';
            app.ObjectMasskgSpinner = uispinner(app.UIFigure);
            app.ObjectMasskgSpinner.Step = 0.01;
            app.ObjectMasskgSpinner.Limits = [0 1];
            app.ObjectMasskgSpinner.Value = 0.15;
            app.LiftLampLabel = uilabel(app.UIFigure);
            app.LiftLampLabel.Text = 'Lift';
            app.LiftLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Instantaneous Pressure')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Pressure (kPa)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Lifting Force vs. Pressure')
            xlabel(app.UIAxes2, 'Pressure (kPa)')
            ylabel(app.UIAxes2, 'Lifting Force (N)')
            app.AutomaticSwitchLabel = uilabel(app.UIFigure);
            app.AutomaticSwitchLabel.Text = 'Automatic';
            app.AutomaticSwitch = uiswitch(app.UIFigure, 'slider');
            app.ObjectSizemSpinnerLabel = uilabel(app.UIFigure);
            app.ObjectSizemSpinnerLabel.Text = 'Object Size (m)';
            app.ObjectSizemSpinner = uispinner(app.UIFigure);
            app.ObjectSizemSpinner.Step = 0.005;
            app.ObjectSizemSpinner.Limits = [0.015 0.065];
            app.ObjectSizemSpinner.Value = 0.03;
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end